classdef EEE_212_FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalogModDemodulatorUIFigure  matlab.ui.Figure
        ResetButton                   matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        FrequencyModulationTab        matlab.ui.container.Tab
        Label_8                       matlab.ui.control.Label
        InputsignalButton_2           matlab.ui.control.Button
        Label_3                       matlab.ui.control.Label
        RetrievedSignalButton         matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        SlopedetectorButton           matlab.ui.control.Button
        ChannelButton                 matlab.ui.control.Button
        ModulateButton                matlab.ui.control.Button
        AmplitudeModulationTab        matlab.ui.container.Tab
        Label_7                       matlab.ui.control.Label
        InputsignalButton             matlab.ui.control.Button
        SelectDetectorSwitch          matlab.ui.control.Switch
        SelectDetectorSwitchLabel     matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        RetrievedSignalButton_2       matlab.ui.control.Button
        Label_4                       matlab.ui.control.Label
        DetectorButton                matlab.ui.control.Button
        ChannelButton_2               matlab.ui.control.Button
        ModulateButton_2              matlab.ui.control.Button
        PlayCurrentSignalButton       matlab.ui.control.Button
        NewButton_2                   matlab.ui.control.Button
        OpenButton                    matlab.ui.control.Button
        DomainSwitch                  matlab.ui.control.Switch
        DomainSwitchLabel             matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

 
    properties (Access = private)
        t          % time vector
        tHR        % time vector High Res.
        SignalOrg  % Original signal
        fmSignal   % frequency modulated signal
        amSignal   % amplitude modulated signal
        defmSignal % demodulated fm signal
        deamSignal % demodulated am signal
        
        CurrentY    % 
        CurrentX    % Description
        domain      % Description
        recorderApp % Description
        Len         % Description
        resMult     % Description
        
        Fs         % Sample rate
        FsM        % Sample rate multipler
        amdType    % amplitude demodulator type 
        plotTitle  % title of current plot
        
      
    end

    properties (Access = public)
    end

    methods (Access = private)
        
        function results = updatePlot(app)
            if isempty(app.CurrentY)
                return;
            end
            if length(app.CurrentX) ~= length(app.CurrentY)
                return;
            end
            if app.domain == true
                plot(app.UIAxes, app.CurrentX, app.CurrentY);
                title(app.UIAxes, strcat(app.plotTitle, ' in time domain'));
                xlabel(app.UIAxes, 'time');
                xlim(app.UIAxes, [min(app.CurrentX), max(app.CurrentX)]);
                ylim(app.UIAxes, [min(app.CurrentY) - 0.1, max(app.CurrentY) + 0.1]);
            else
                Y = fft(app.CurrentY);
                L = length(app.CurrentY);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = app.Fs*(0:(L/2))/L;
                
                plot(app.UIAxes, f,P1); 
                title(app.UIAxes, strcat(app.plotTitle, ' in frequency domain'));
                xlabel(app.UIAxes, 'f (Hz)');
                ylabel(app.UIAxes, 'Intensity');
                xlim(app.UIAxes, [min(f), max(f)]);
                ylim(app.UIAxes, [min(P1) - 0.1, max(P1) + 0.1]);
            end
        end


        function results = reset(app)
            app.ModulateButton.Enable = 'on';
            app.SlopedetectorButton.Enable = 'on';
            app.ModulateButton_2.Enable = 'on';
            app.DetectorButton.Enable = 'on';
            app.FsM = 1;
            %reset all data
            app.fmSignal(1:end) = 0;
            app.amSignal(1:end) = 0;
            app.defmSignal(1:end) = 0;
            app.deamSignal(1:end) = 0;
            app.CurrentY(1:end) = 0;
            updatePlot(app);
        end
        
    end

    methods (Access = public)
    
        function results = updateMainSignal(app, signal)
            reset(app);
            app.plotTitle = 'Input Signal';
            drawnow
            L = length(signal);
            app.SignalOrg = signal;
            app.Fs = 8000;
            app.Len = length(app.SignalOrg)/app.Fs;
            app.t = linspace(0, L/app.Fs, L);
            app.CurrentX = app.t;
            app.CurrentY = app.SignalOrg;
            app.domain = 1;
            updatePlot(app);
            app.NewButton_2.Enable = 'on';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            pan(app.UIAxes,'on');         
            zoom(app.UIAxes,'on'); 
            app.FsM = 1;
            app.amdType = 1;
        end

        % Value changed function: DomainSwitch
        function DomainSwitchValueChanged(app, event)
            app.domain = ~app.domain;
            updatePlot(app);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.wav'}, 'select audio file');
            disp(strcat(pathname, filename));
            
            %if nothing is selected, don't plot anything
            if pathname == 0
                return;
            end
            [app.SignalOrg, app.Fs] = audioread(strcat(pathname, filename));
            updateMainSignal(app, app.SignalOrg);
        end

        % Button pushed function: NewButton_2
        function NewButton_2Pushed(app, event)
            app.NewButton_2.Enable = 'off';
            app.recorderApp = Recorder(app);
        end

        % Button pushed function: PlayCurrentSignalButton
        function PlayCurrentSignalButtonPushed(app, event)
            sound(app.CurrentY, app.Fs * app.FsM);
        end

        % Button pushed function: ModulateButton
        function ModulateButtonPushed(app, event)
            %frequency modulation
            if isempty(app.SignalOrg) == 1
                return;
            end
            app.plotTitle = 'Frequency Modulated Signal';
            fc = 1600;
            k = 400;
            app.resMult = 4;
            app.tHR = linspace(0, app.Len, app.Len * app.resMult * app.Fs);
            yn = interp1(app.t, app.SignalOrg, app.tHR);
            freqDev = cumsum((app.tHR(2) - app.tHR(1)) * yn) * k;
            app.fmSignal = cos( 2*pi*(freqDev + fc * app.tHR));
            app.CurrentX = app.tHR;
            app.CurrentY = app.fmSignal;
            updatePlot(app);
            ylim(app.UIAxes, [-1, 1]);
            app.ModulateButton.Enable = 'off';

        end

        % Button pushed function: ChannelButton
        function ChannelButton_2Pushed(app, event)
            if isempty(app.fmSignal) == 1
                return;
            end
                app.FsM = 4;
                app.CurrentX = app.tHR;
                app.CurrentY = app.fmSignal;
                updatePlot(app);
        end

        % Button pushed function: DetectorButton
        function DetectorButtonPushed(app, event)
            %Amplitude demodulator
            if isempty(app.amSignal) == 1
                return;
            end
            
            app.deamSignal = app.amSignal;
            app.deamSignal(app.deamSignal < 0) = 0;
            app.plotTitle = 'Rectifier demodulated signal';
            if app.amdType == 1
                app.deamSignal = envelope(app.deamSignal);
                app.plotTitle = 'Envelope demodulatd signal';
            end
            app.FsM = 4;
            app.DetectorButton.Enable = 'off';
            app.CurrentX = app.tHR;
            app.CurrentY = app.deamSignal;
            updatePlot(app);
        end

        % Button pushed function: SlopedetectorButton
        function SlopedetectorButtonPushed(app, event)
            if isempty(app.fmSignal) == 1
                return;
            end
            dmSignal = 3 * envelope(diff(app.fmSignal));
            app.defmSignal = dmSignal/max(dmSignal);
            app.CurrentY = app.defmSignal;
            app.CurrentX = app.tHR(1:end-1);
            app.FsM = 4;
            updatePlot(app);
            ylim(app.UIAxes, [min(app.CurrentY), max(app.CurrentY)]);
            app.SlopedetectorButton.Enable = 'off';
        end

        % Button pushed function: RetrievedSignalButton
        function RetrievedSignalButtonPushed(app, event)
            if isempty(app.defmSignal) == 1
                return;
            end
            app.FsM = 4;
            app.CurrentY = app.defmSignal;
            app.CurrentX = app.tHR(1:end-1);
            app.plotTitle = 'Frequency demodulated signal';
            updatePlot(app);
        end

        % Button pushed function: ModulateButton_2
        function ModulateButton_2Pushed(app, event)
            %amplitude modulation
            if isempty(app.SignalOrg) == 1
                return;
            end
            app.plotTitle = 'Amplitude Modulated Signal';
            fc = 100;
            app.resMult = 4;
            app.FsM = 4;
            app.tHR = linspace(0, app.Len, app.Len * app.resMult * app.Fs);
            yn = interp1(app.t, app.SignalOrg, app.tHR);
            app.amSignal = yn .* cos(2 * pi * fc * app.tHR);
            app.CurrentX = app.tHR;
            app.CurrentY = app.amSignal;
            updatePlot(app);
            app.ModulateButton_2.Enable = 'off';
        end

        % Value changed function: SelectDetectorSwitch
        function SelectDetectorSwitchValueChanged(app, event)
            value = app.SelectDetectorSwitch.Value;
            switch value
                case 'Envelope'
                    app.amdType = 1;
                case 'Rectifier'
                    app.amdType = 0;
            end
            disp(app.amdType);
        end

        % Button pushed function: ChannelButton_2
        function ChannelButton_2Pushed2(app, event)
            if isempty(app.amSignal) == 1
                return;
            end
            app.FsM = 4;
            app.plotTitle = 'Amplitude modulated signal';
            app.CurrentX = app.tHR;
            app.CurrentY = app.amSignal;
            updatePlot(app);
        end

        % Button pushed function: RetrievedSignalButton_2
        function RetrievedSignalButton_2Pushed(app, event)
            %am retrieved signal
            if isempty(app.deamSignal) == 1
                return;
            end
            app.FsM = 4;
            app.plotTitle = 'Amplitude demodulated signal';
            app.CurrentX = app.tHR;
            app.CurrentY = app.deamSignal;
            updatePlot(app);
        end

        % Button pushed function: InputsignalButton, 
        % InputsignalButton_2
        function InputsignalButtonPushed(app, event)
            %plot original signal
            app.CurrentX = app.t;
            app.CurrentY  = app.SignalOrg;
            app.FsM = 1;
            app.plotTitle = 'Input Signal';
            updatePlot(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %reset all data
            reset(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalogModDemodulatorUIFigure = uifigure('Visible', 'off');
            app.AnalogModDemodulatorUIFigure.Name = 'Analog Mod-Demodulator';
            app.UIAxes = uiaxes(app.AnalogModDemodulatorUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DomainSwitchLabel = uilabel(app.AnalogModDemodulatorUIFigure);
            app.DomainSwitchLabel.Text = 'Domain';
            app.DomainSwitch = uiswitch(app.AnalogModDemodulatorUIFigure, 'slider');
            app.DomainSwitch.Items = {'Time', 'Fre.'};
            app.DomainSwitch.ValueChangedFcn = createCallbackFcn(app, @DomainSwitchValueChanged, true);
            app.DomainSwitch.Value = 'Time';
            app.OpenButton = uibutton(app.AnalogModDemodulatorUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.NewButton_2 = uibutton(app.AnalogModDemodulatorUIFigure, 'push');
            app.NewButton_2.ButtonPushedFcn = createCallbackFcn(app, @NewButton_2Pushed, true);
            app.NewButton_2.Text = 'New';
            app.PlayCurrentSignalButton = uibutton(app.AnalogModDemodulatorUIFigure, 'push');
            app.PlayCurrentSignalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayCurrentSignalButtonPushed, true);
            app.PlayCurrentSignalButton.Text = 'Play Current Signal';
            app.TabGroup = uitabgroup(app.AnalogModDemodulatorUIFigure);
            app.FrequencyModulationTab = uitab(app.TabGroup);
            app.FrequencyModulationTab.Title = 'Frequency Modulation';
            app.ModulateButton = uibutton(app.FrequencyModulationTab, 'push');
            app.ModulateButton.ButtonPushedFcn = createCallbackFcn(app, @ModulateButtonPushed, true);
            app.ModulateButton.Text = 'Modulate';
            app.ChannelButton = uibutton(app.FrequencyModulationTab, 'push');
            app.ChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelButton_2Pushed, true);
            app.ChannelButton.Text = 'Channel';
            app.SlopedetectorButton = uibutton(app.FrequencyModulationTab, 'push');
            app.SlopedetectorButton.ButtonPushedFcn = createCallbackFcn(app, @SlopedetectorButtonPushed, true);
            app.SlopedetectorButton.Text = 'Slope detector';
            app.Label = uilabel(app.FrequencyModulationTab);
            app.Label.Text = ' --->';
            app.Label_2 = uilabel(app.FrequencyModulationTab);
            app.Label_2.Text = ' --->';
            app.RetrievedSignalButton = uibutton(app.FrequencyModulationTab, 'push');
            app.RetrievedSignalButton.ButtonPushedFcn = createCallbackFcn(app, @RetrievedSignalButtonPushed, true);
            app.RetrievedSignalButton.Text = 'Retrieved Signal';
            app.Label_3 = uilabel(app.FrequencyModulationTab);
            app.Label_3.Text = ' --->';
            app.InputsignalButton_2 = uibutton(app.FrequencyModulationTab, 'push');
            app.InputsignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @InputsignalButtonPushed, true);
            app.InputsignalButton_2.Text = 'Input signal';
            app.Label_8 = uilabel(app.FrequencyModulationTab);
            app.Label_8.Text = ' --->';
            app.AmplitudeModulationTab = uitab(app.TabGroup);
            app.AmplitudeModulationTab.Title = 'Amplitude Modulation';
            app.ModulateButton_2 = uibutton(app.AmplitudeModulationTab, 'push');
            app.ModulateButton_2.ButtonPushedFcn = createCallbackFcn(app, @ModulateButton_2Pushed, true);
            app.ModulateButton_2.Text = 'Modulate';
            app.ChannelButton_2 = uibutton(app.AmplitudeModulationTab, 'push');
            app.ChannelButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChannelButton_2Pushed2, true);
            app.ChannelButton_2.Text = 'Channel';
            app.DetectorButton = uibutton(app.AmplitudeModulationTab, 'push');
            app.DetectorButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorButtonPushed, true);
            app.DetectorButton.Text = 'Detector';
            app.Label_4 = uilabel(app.AmplitudeModulationTab);
            app.Label_4.Text = ' --->';
            app.RetrievedSignalButton_2 = uibutton(app.AmplitudeModulationTab, 'push');
            app.RetrievedSignalButton_2.ButtonPushedFcn = createCallbackFcn(app, @RetrievedSignalButton_2Pushed, true);
            app.RetrievedSignalButton_2.Text = 'Retrieved Signal';
            app.Label_5 = uilabel(app.AmplitudeModulationTab);
            app.Label_5.Text = ' --->';
            app.Label_6 = uilabel(app.AmplitudeModulationTab);
            app.Label_6.Text = ' --->';
            app.SelectDetectorSwitchLabel = uilabel(app.AmplitudeModulationTab);
            app.SelectDetectorSwitchLabel.Text = 'Select Detector';
            app.SelectDetectorSwitch = uiswitch(app.AmplitudeModulationTab, 'slider');
            app.SelectDetectorSwitch.Items = {'Envelope', 'Rectifier'};
            app.SelectDetectorSwitch.ValueChangedFcn = createCallbackFcn(app, @SelectDetectorSwitchValueChanged, true);
            app.SelectDetectorSwitch.Value = 'Envelope';
            app.InputsignalButton = uibutton(app.AmplitudeModulationTab, 'push');
            app.InputsignalButton.ButtonPushedFcn = createCallbackFcn(app, @InputsignalButtonPushed, true);
            app.InputsignalButton.Text = 'Input signal';
            app.Label_7 = uilabel(app.AmplitudeModulationTab);
            app.Label_7.Text = ' --->';
            app.ResetButton = uibutton(app.AnalogModDemodulatorUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.AnalogModDemodulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEE_212_FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalogModDemodulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalogModDemodulatorUIFigure)
        end
    end
end