classdef Recorder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        PlayButton              matlab.ui.control.Button
        RetryButton             matlab.ui.control.Button
        DoneButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        DurationEditField       matlab.ui.control.NumericEditField
        DurationEditFieldLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        duration % Description
        recording % Description
        callerApp % Description
    end

    properties (Access = public)
        input % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.duration = 0;
            app.callerApp = mainApp;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            recobj = audiorecorder;
            recordblocking(recobj, app.duration);
            app.recording = getaudiodata(recobj);
            t = linspace(0, app.duration, app.duration * 8000);
            plot(app.UIAxes, t, app.recording);
        end

        % Value changed function: DurationEditField
        function DurationEditFieldValueChanged(app, event)
            value = app.DurationEditField.Value;
            app.duration = value;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if isempty(app.recording) == 0
                app.callerApp.updateMainSignal(app.recording);
                
                %audiowrite('record1.wav', app.recording, 8000);
            end
            
            close(app.UIFigure);
        end

        % Button pushed function: RetryButton
        function RetryButtonPushed(app, event)
            app.recording = zeros(1, length(linspace(0, app.duration, 8000)));
            plot(app.UIAxes, app.recording);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.recording, 8000);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Recording')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditField.ValueChangedFcn = createCallbackFcn(app, @DurationEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.RetryButton = uibutton(app.UIFigure, 'push');
            app.RetryButton.ButtonPushedFcn = createCallbackFcn(app, @RetryButtonPushed, true);
            app.RetryButton.Text = 'Retry';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recorder(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end